classdef tron < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TranscodingRemuxingOperationsNucleusTRONLabel  matlab.ui.control.Label
        GeneratecommandsforqualitycontrolhereLabel  matlab.ui.control.Label
        ShareddirectorypathnameTextArea  matlab.ui.control.TextArea
        ShareddirectorypathnameTextAreaLabel  matlab.ui.control.Label
        OutputfilenameTextArea         matlab.ui.control.TextArea
        OutputfilenameTextAreaLabel    matlab.ui.control.Label
        InputfilenameTextArea          matlab.ui.control.TextArea
        InputfilenameTextAreaLabel     matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        GeneratecommandsButton         matlab.ui.control.Button
        TypedesiredoutputdirectoryintheeditfieldLabel  matlab.ui.control.Label
        OutputdirectoryEditField       matlab.ui.control.EditField
        OutputdirectoryEditFieldLabel  matlab.ui.control.Label
        InputButton                    matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InputButton
        function InputButtonPushed(app, event)
            [file, path] = uigetfile('*.mxf;*.mkv;*.avi', 'Select Video File');
            if isequal(file, 0)
                disp('User selected cancel.');
            else
                inputfile = fullfile(path,file);
                outputfile = (app.OutputdirectoryEditField.Value + "output.mp4");
                ffmpegCommand = sprintf('ffmpeg -i %s -map 0 -c:a copy %s', inputfile, outputfile);

                % Execute the FFmpeg command 
                status = system(ffmpegCommand); 
                % Check the status of the conversion process 
                if status == 0 
                    disp('Conversion completed. Look for "output.mp4" in the output directory you specified.'); 
                else 
                    disp('Conversion failed.');
                end
end 
        end

        % Button pushed function: GeneratecommandsButton
        function GeneratecommandsButtonPushed(app, event)
           str0 = string(app.ShareddirectorypathnameTextArea.Value);
           str1 = string(app.InputfilenameTextArea.Value);
           str2 = string(app.OutputfilenameTextArea.Value);
           app.TextArea.Value = sprintf(append('cd ', str0, '\n', '\n', 'ffplay -f lavfi "movie=', str1, '[org]; movie=', str2, '[enc]; [org][enc]blend=all_mode=difference"'));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputButton = uibutton(app.UIFigure, 'push');
            app.InputButton.ButtonPushedFcn = createCallbackFcn(app, @InputButtonPushed, true);
            app.InputButton.Text = 'Input';
            app.OutputdirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.OutputdirectoryEditFieldLabel.Text = 'Output directory';
            app.OutputdirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.TypedesiredoutputdirectoryintheeditfieldLabel = uilabel(app.UIFigure);
            app.TypedesiredoutputdirectoryintheeditfieldLabel.Text = {'Convert MXF, AVI, or MKV to MP4:'; '1. Type desired output directory for MP4 file in the edit field.'; '2. Click the "Input" button and select the MXF file you want to convert.'; '3. Check the MatLab command window for success/failure notification.'};
            app.GeneratecommandsButton = uibutton(app.UIFigure, 'push');
            app.GeneratecommandsButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratecommandsButtonPushed, true);
            app.GeneratecommandsButton.Text = 'Generate commands';
            app.TextArea = uitextarea(app.UIFigure);
            app.InputfilenameTextAreaLabel = uilabel(app.UIFigure);
            app.InputfilenameTextAreaLabel.Text = 'Input file name';
            app.InputfilenameTextArea = uitextarea(app.UIFigure);
            app.OutputfilenameTextAreaLabel = uilabel(app.UIFigure);
            app.OutputfilenameTextAreaLabel.Text = 'Output file name';
            app.OutputfilenameTextArea = uitextarea(app.UIFigure);
            app.ShareddirectorypathnameTextAreaLabel = uilabel(app.UIFigure);
            app.ShareddirectorypathnameTextAreaLabel.Text = 'Shared directory pathname';
            app.ShareddirectorypathnameTextArea = uitextarea(app.UIFigure);
            app.GeneratecommandsforqualitycontrolhereLabel = uilabel(app.UIFigure);
            app.GeneratecommandsforqualitycontrolhereLabel.Text = 'Generate commands for quality control here:';
            app.TranscodingRemuxingOperationsNucleusTRONLabel = uilabel(app.UIFigure);
            app.TranscodingRemuxingOperationsNucleusTRONLabel.Text = 'Transcoding & Remuxing Operations Nucleus (TRON)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tron

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end